classdef GUI_BDS3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        SliderTab           matlab.ui.container.Tab
        ZSliderLabel        matlab.ui.control.Label
        ZSlider             matlab.ui.control.Slider
        ZsMCTextAreaLabel   matlab.ui.control.Label
        ZsMCTextArea        matlab.ui.control.TextArea
        ZMCTextAreaLabel    matlab.ui.control.Label
        ZMCTextArea         matlab.ui.control.TextArea
        MenuPanel           matlab.ui.container.Panel
        StatoLampLabel      matlab.ui.control.Label
        StatoLamp           matlab.ui.control.Lamp
        LoadButton          matlab.ui.control.Button
        DrawingButtonGroup  matlab.ui.container.ButtonGroup
        ViewmodeButton      matlab.ui.control.ToggleButton
        DrawmodeButton      matlab.ui.control.ToggleButton
        ApplyButton         matlab.ui.control.Button
        SMCPanel            matlab.ui.container.Panel
        MCPanel             matlab.ui.container.Panel
        SkeletonTab         matlab.ui.container.Tab
        SaveButton          matlab.ui.control.Button
        DMTextAreaLabel     matlab.ui.control.Label
        DMTextArea          matlab.ui.control.TextArea
        FDTextAreaLabel     matlab.ui.control.Label
        FDTextArea          matlab.ui.control.TextArea
        NTTextAreaLabel     matlab.ui.control.Label
        NTTextArea          matlab.ui.control.TextArea
        VVDTextAreaLabel    matlab.ui.control.Label
        VVDTextArea         matlab.ui.control.TextArea
        NBTextAreaLabel     matlab.ui.control.Label
        NBTextArea          matlab.ui.control.TextArea
        VOICNLabel          matlab.ui.control.Label
        VOIONLabel          matlab.ui.control.Label
        DMTextArea_2Label   matlab.ui.control.Label
        DMTextArea_2        matlab.ui.control.TextArea
        FDTextArea_2Label   matlab.ui.control.Label
        FDTextArea_2        matlab.ui.control.TextArea
        NTTextArea_2Label   matlab.ui.control.Label
        NTTextArea_2        matlab.ui.control.TextArea
        VVDTextArea_2Label  matlab.ui.control.Label
        VVDTextArea_2       matlab.ui.control.TextArea
        NBTextArea_2Label   matlab.ui.control.Label
        NBTextArea_2        matlab.ui.control.TextArea
        VOICNLabel_2        matlab.ui.control.Label
        VOIONLabel_2        matlab.ui.control.Label
        DMTextArea_3Label   matlab.ui.control.Label
        DMTextArea_3        matlab.ui.control.TextArea
        FDTextArea_3Label   matlab.ui.control.Label
        FDTextArea_3        matlab.ui.control.TextArea
        NTTextArea_3Label   matlab.ui.control.Label
        NTTextArea_3        matlab.ui.control.TextArea
        VVDTextArea_3Label  matlab.ui.control.Label
        VVDTextArea_3       matlab.ui.control.TextArea
        NBTextArea_3Label   matlab.ui.control.Label
        NBTextArea_3        matlab.ui.control.TextArea
        DMTextArea_4Label   matlab.ui.control.Label
        DMTextArea_4        matlab.ui.control.TextArea
        FDTextArea_4Label   matlab.ui.control.Label
        FDTextArea_4        matlab.ui.control.TextArea
        NTTextArea_4Label   matlab.ui.control.Label
        NTTextArea_4        matlab.ui.control.TextArea
        VVDTextArea_4Label  matlab.ui.control.Label
        VVDTextArea_4       matlab.ui.control.TextArea
        NBTextArea_4Label   matlab.ui.control.Label
        NBTextArea_4        matlab.ui.control.TextArea
        UIAxes              matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        Tab                 matlab.ui.container.Tab
        TextArea            matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        % Tutte le info sul caricamento, volume logical e full volume
        c
        
        % Assi nei panel
        ImageAxes_MC
        ImageAxes_SMC
        Axes_skel_SMC
        
        % ROI disegnate da drawrectangle
        ROI_SMC_CN  % CN 
        ROI_MC_CN  % CN
        ROI_SMC_ON  % ON
        ROI_MC_ON  % ON
        
        % variabili per il salvataggio dei parametri
        % CN-->center nodule
        % ON-->outer nodule
        % SMC-->senza mezzo di contrasto
        % MC-->con mezzo di contrasto
        SMC_CN_params
        SMC_ON_params
        MC_CN_params
        MC_ON_params
        
        % Oggetto sk: skeleton, centerline
        sk_MC
        sk_SMC
        
        % flag per il primo caricamento
        first_load=1;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            if app.first_load==0 % eseguo l'if se non sono al 1°caricamento
                % cancello i grafici precedenti
                delete(app.ImageAxes_SMC);
                delete(app.ImageAxes_MC);
                app.UIAxes.cla;
                app.UIAxes2.cla;
            end
            
            app.TextArea.Value='Loading your images...';
            app.c = Caricamento();
            
            if isempty(app.c.states)
                
                %% Inizializzazione slider tab
                % caricamento e creazione di immagini e volumi
                app.c.creaVol;
                app.ImageAxes_SMC = axes(app.SMCPanel); 
                imshow(app.c.volume_SMC(:,:,:,1), "Parent", app.ImageAxes_SMC);
                app.ImageAxes_MC = axes(app.MCPanel); 
                imshow(app.c.volume_MC(:,:,:,1), "Parent", app.ImageAxes_MC);
                
                app.StatoLamp.Color = "green";
                
                % Inizializzazione slider e caselle di testo (mostrano la "fetta visualizzata")
                if size(app.c.volume_MC,4) >= size(app.c.volume_SMC,4)
                    app.ZSlider.Limits = [1, size(app.c.volume_MC,4)];
                else
                    app.ZSlider.Limits = [1, size(app.c.volume_SMC,4)];
                end
                app.ZsMCTextArea.Value = "1";
                app.ZMCTextArea.Value = "1";
                app.ZSlider.Enable='on';
                
                app.TextArea.Value='Rendering the vascular network...';
                
                %% Inizializzazione skeleton tab
                % creazione skeleton
                app.sk_MC = skelskel(app.c.volume_logical_MC);
                app.sk_SMC = skelskel(app.c.volume_logical_SMC);
                % visualizzazione skeleton SMC
                p1 = patch(app.UIAxes, isosurface(logical(app.sk_SMC.skeleton), 1/2));
                p1.FaceColor = 'green';
                p1.EdgeColor = 'none';
                camlight(app.UIAxes);
                lighting(app.UIAxes, 'gouraud');
                view(app.UIAxes, 3);
                zlabel(app.UIAxes,'Z');
                % visualizzazione skeleton MC
                p2 = patch(app.UIAxes2, isosurface(logical(app.sk_MC.skeleton), 1/2));
                p2.FaceColor = 'green';
                p2.EdgeColor = 'none';
                camlight(app.UIAxes2);
                lighting(app.UIAxes2,'gouraud');
                view(app.UIAxes2, 3);
                zlabel(app.UIAxes2,'Z');             
            else
                f = warndlg(app.c.states,'Warning');
                app.StatoLamp.Color = "red";
            end
            
            app.TextArea.Value='Select the "Draw mode" to select the desired VOIs...';
            
            app.first_load=0;
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            % Varia la "fetta" mostrata
            value = floor(app.ZSlider.Value);
            if value <= size(app.c.volume_SMC,4)
                imshow(app.c.volume_SMC(:,:,:,value), "Parent", app.ImageAxes_SMC);
                fetta = sprintf("%d", value);
                app.ZsMCTextArea.Value = fetta;
            else
                imshow(app.c.volume_SMC(:,:,:,end), "Parent", app.ImageAxes_SMC);
                fetta = sprintf("%d", size(app.c.volume_SMC,4));
                app.ZsMCTextArea.Value = fetta;
            end
            
            % controllo per evitare che volumi di spessore diverso causino un errore 
            if value <= size(app.c.volume_MC,4)
                imshow(app.c.volume_MC(:,:,:,value), "Parent", app.ImageAxes_MC);
                fetta = sprintf("%d", value);
                app.ZMCTextArea.Value = fetta;
            else
                imshow(app.c.volume_MC(:,:,:,end), "Parent", app.ImageAxes_MC);
                fetta = sprintf("%d", size(app.c.volume_MC,4));
                app.ZMCTextArea.Value = fetta;
            end
        end

        % Selection changed function: DrawingButtonGroup
        function DrawingButtonGroupSelectionChanged(app, event)

            selectedButton = app.DrawingButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "View mode"
                    % cancello le ROI precedentemente visualizzate
                    delete(app.ROI_SMC_CN);
                    delete(app.ROI_MC_CN);
                    delete(app.ROI_SMC_ON);
                    delete(app.ROI_MC_ON);
                    app.ApplyButton.Visible = "off";
                    
                case "Draw mode"
                    % inizializzo 2 ROI su ogni immagine
                    app.TextArea.Value='CN = center nodule | ON = outer nodule | Press "Apply" to calculate the tortuosity parameter...';
                    app.ApplyButton.Visible = "on";
                    % il primo è per la x, il secondo per la y, il pixel in alto a sx è l'1,1
                    app.ROI_SMC_CN = drawrectangle(app.ImageAxes_SMC, 'Label','CN','Color',[1 0 0], ...
                        'Position',[1,1,50,50], 'InteractionsAllowed', "translate");
                    app.ROI_SMC_ON = drawrectangle(app.ImageAxes_SMC, 'Label','ON','Color',"y", ...
                        'Position',[52,52,50,50], 'InteractionsAllowed', "translate");
                    app.ROI_MC_CN = drawrectangle(app.ImageAxes_MC, 'Label','CN','Color',[1 0 0], ...
                        'Position',[1,1,50,50], 'InteractionsAllowed', "translate");
                    app.ROI_MC_ON = drawrectangle(app.ImageAxes_MC, 'Label','ON','Color',"y", ...
                        'Position',[52,52,50,50], 'InteractionsAllowed', "translate");   
                    
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
       
            app.TextArea.Value='Calculating the tortuosity parameters...';
            % partendo dalle ROI isolo il volume (VOI) sull'immagine
            z_sMC = str2num(app.ZsMCTextArea.Value{1});
            voi_SMC = voiManager(app.sk_SMC, app.ROI_SMC_CN, app.ROI_SMC_ON, z_sMC, 25);
            voi_SMC.computeVoi()
            z_MC = str2num(app.ZsMCTextArea.Value{1});
            voi_MC = voiManager(app.sk_MC, app.ROI_MC_CN, app.ROI_MC_ON, z_MC, 25);
            voi_MC.computeVoi()
            
            % Calcolo parametri di tortuosità sui 4 VOI
            [n_alberi,n_ramif,DM,DF,VVD]=tortuosity_parameters(voi_SMC.cl_CN,voi_SMC.skel_CN);
            app.SMC_CN_params=[n_alberi,n_ramif,DM,DF,VVD]; % variabile per il salvataggio
            app.DMTextArea.Value=sprintf("%.3f", round(DM,3));
            app.FDTextArea.Value=sprintf("%.3f", round(DF,3));
            app.NTTextArea.Value=sprintf("%i", n_alberi);
            app.NBTextArea.Value=sprintf("%i", n_ramif);
            app.VVDTextArea.Value=sprintf("%.2f", round(VVD*100,2));
            
            [n_alberi,n_ramif,DM,DF,VVD]=tortuosity_parameters(voi_SMC.cl_ON,voi_SMC.skel_ON);
            app.SMC_ON_params=[n_alberi,n_ramif,DM,DF,VVD]; % variabile per il salvataggio
            app.DMTextArea_2.Value=sprintf("%.3f", round(DM,3));
            app.FDTextArea_2.Value=sprintf("%.3f", round(DF,3));
            app.NTTextArea_2.Value=sprintf("%i", n_alberi);
            app.NBTextArea_2.Value=sprintf("%i", n_ramif);
            app.VVDTextArea_2.Value=sprintf("%.2f", round(VVD*100,2));
            
            [n_alberi,n_ramif,DM,DF,VVD]=tortuosity_parameters(voi_MC.cl_CN,voi_MC.skel_CN);
            app.MC_CN_params=[n_alberi,n_ramif,DM,DF,VVD]; % variabile per il salvataggio
            app.DMTextArea_3.Value=sprintf("%.3f", round(DM,3));
            app.FDTextArea_3.Value=sprintf("%.3f", round(DF,3));
            app.NTTextArea_3.Value=sprintf("%i", n_alberi);
            app.NBTextArea_3.Value=sprintf("%i", n_ramif);
            app.VVDTextArea_3.Value=sprintf("%.2f", round(VVD*100,2));
            
            [n_alberi,n_ramif,DM,DF,VVD]=tortuosity_parameters(voi_MC.cl_ON,voi_MC.skel_ON);
            app.MC_ON_params=[n_alberi,n_ramif,DM,DF,VVD]; % variabile per il salvataggio
            app.DMTextArea_4.Value=sprintf("%.3f", round(DM,3));
            app.FDTextArea_4.Value=sprintf("%.3f", round(DF,3));
            app.NTTextArea_4.Value=sprintf("%i", n_alberi);
            app.NBTextArea_4.Value=sprintf("%i", n_ramif);
            app.VVDTextArea_4.Value=sprintf("%.2f", round(VVD*100,2));
            
            app.TextArea.Value='Go in the "Skeleton" section to visualize the totuosity parameters...';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
             % salvataggio dati
            tortuosity_params=[app.SMC_CN_params app.SMC_ON_params];
            save('SMC_params.mat','tortuosity_params')
            tortuosity_params=[app.MC_CN_params app.MC_ON_params];
            save('MC_params.mat','tortuosity_params')
            app.TextArea.Value='Parameters saved...';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SliderTab = uitab(app.TabGroup);
            app.SliderTab.Title = 'Slider';
            app.ZSliderLabel = uilabel(app.SliderTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.SliderTab);
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.Enable = 'off';
            app.ZsMCTextAreaLabel = uilabel(app.SliderTab);
            app.ZsMCTextAreaLabel.Text = 'Z sMC';
            app.ZsMCTextArea = uitextarea(app.SliderTab);
            app.ZsMCTextArea.Editable = 'off';
            app.ZMCTextAreaLabel = uilabel(app.SliderTab);
            app.ZMCTextAreaLabel.Text = 'Z MC';
            app.ZMCTextArea = uitextarea(app.SliderTab);
            app.ZMCTextArea.Editable = 'off';
            app.MenuPanel = uipanel(app.SliderTab);
            app.MenuPanel.Title = 'Menu';
            app.StatoLampLabel = uilabel(app.MenuPanel);
            app.StatoLampLabel.Text = 'Stato';
            app.StatoLamp = uilamp(app.MenuPanel);
            app.LoadButton = uibutton(app.MenuPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DrawingButtonGroup = uibuttongroup(app.MenuPanel);
            app.DrawingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DrawingButtonGroupSelectionChanged, true);
            app.DrawingButtonGroup.Title = 'Drawing';
            app.ViewmodeButton = uitogglebutton(app.DrawingButtonGroup);
            app.ViewmodeButton.Text = 'View mode';
            app.ViewmodeButton.Value = true;
            app.DrawmodeButton = uitogglebutton(app.DrawingButtonGroup);
            app.DrawmodeButton.Text = 'Draw mode';
            app.ApplyButton = uibutton(app.MenuPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SMCPanel = uipanel(app.SliderTab);
            app.SMCPanel.Title = 'SMC';
            app.MCPanel = uipanel(app.SliderTab);
            app.MCPanel.Title = 'MC';
            app.SkeletonTab = uitab(app.TabGroup);
            app.SkeletonTab.Title = 'Skeleton';
            app.SaveButton = uibutton(app.SkeletonTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DMTextAreaLabel = uilabel(app.SkeletonTab);
            app.DMTextAreaLabel.Text = 'DM';
            app.DMTextArea = uitextarea(app.SkeletonTab);
            app.FDTextAreaLabel = uilabel(app.SkeletonTab);
            app.FDTextAreaLabel.Text = 'FD';
            app.FDTextArea = uitextarea(app.SkeletonTab);
            app.NTTextAreaLabel = uilabel(app.SkeletonTab);
            app.NTTextAreaLabel.Text = 'NT';
            app.NTTextArea = uitextarea(app.SkeletonTab);
            app.VVDTextAreaLabel = uilabel(app.SkeletonTab);
            app.VVDTextAreaLabel.Text = 'VVD';
            app.VVDTextArea = uitextarea(app.SkeletonTab);
            app.NBTextAreaLabel = uilabel(app.SkeletonTab);
            app.NBTextAreaLabel.Text = 'NB';
            app.NBTextArea = uitextarea(app.SkeletonTab);
            app.VOICNLabel = uilabel(app.SkeletonTab);
            app.VOICNLabel.Text = 'VOI CN';
            app.VOIONLabel = uilabel(app.SkeletonTab);
            app.VOIONLabel.Text = 'VOI ON';
            app.DMTextArea_2Label = uilabel(app.SkeletonTab);
            app.DMTextArea_2Label.Text = 'DM';
            app.DMTextArea_2 = uitextarea(app.SkeletonTab);
            app.FDTextArea_2Label = uilabel(app.SkeletonTab);
            app.FDTextArea_2Label.Text = 'FD';
            app.FDTextArea_2 = uitextarea(app.SkeletonTab);
            app.NTTextArea_2Label = uilabel(app.SkeletonTab);
            app.NTTextArea_2Label.Text = 'NT';
            app.NTTextArea_2 = uitextarea(app.SkeletonTab);
            app.VVDTextArea_2Label = uilabel(app.SkeletonTab);
            app.VVDTextArea_2Label.Text = 'VVD';
            app.VVDTextArea_2 = uitextarea(app.SkeletonTab);
            app.NBTextArea_2Label = uilabel(app.SkeletonTab);
            app.NBTextArea_2Label.Text = 'NB';
            app.NBTextArea_2 = uitextarea(app.SkeletonTab);
            app.VOICNLabel_2 = uilabel(app.SkeletonTab);
            app.VOICNLabel_2.Text = 'VOI CN';
            app.VOIONLabel_2 = uilabel(app.SkeletonTab);
            app.VOIONLabel_2.Text = 'VOI ON';
            app.DMTextArea_3Label = uilabel(app.SkeletonTab);
            app.DMTextArea_3Label.Text = 'DM';
            app.DMTextArea_3 = uitextarea(app.SkeletonTab);
            app.FDTextArea_3Label = uilabel(app.SkeletonTab);
            app.FDTextArea_3Label.Text = 'FD';
            app.FDTextArea_3 = uitextarea(app.SkeletonTab);
            app.NTTextArea_3Label = uilabel(app.SkeletonTab);
            app.NTTextArea_3Label.Text = 'NT';
            app.NTTextArea_3 = uitextarea(app.SkeletonTab);
            app.VVDTextArea_3Label = uilabel(app.SkeletonTab);
            app.VVDTextArea_3Label.Text = 'VVD';
            app.VVDTextArea_3 = uitextarea(app.SkeletonTab);
            app.NBTextArea_3Label = uilabel(app.SkeletonTab);
            app.NBTextArea_3Label.Text = 'NB';
            app.NBTextArea_3 = uitextarea(app.SkeletonTab);
            app.DMTextArea_4Label = uilabel(app.SkeletonTab);
            app.DMTextArea_4Label.Text = 'DM';
            app.DMTextArea_4 = uitextarea(app.SkeletonTab);
            app.FDTextArea_4Label = uilabel(app.SkeletonTab);
            app.FDTextArea_4Label.Text = 'FD';
            app.FDTextArea_4 = uitextarea(app.SkeletonTab);
            app.NTTextArea_4Label = uilabel(app.SkeletonTab);
            app.NTTextArea_4Label.Text = 'NT';
            app.NTTextArea_4 = uitextarea(app.SkeletonTab);
            app.VVDTextArea_4Label = uilabel(app.SkeletonTab);
            app.VVDTextArea_4Label.Text = 'VVD';
            app.VVDTextArea_4 = uitextarea(app.SkeletonTab);
            app.NBTextArea_4Label = uilabel(app.SkeletonTab);
            app.NBTextArea_4Label.Text = 'NB';
            app.NBTextArea_4 = uitextarea(app.SkeletonTab);
            app.UIAxes = uiaxes(app.SkeletonTab);
            title(app.UIAxes, 'SMC')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.SkeletonTab);
            title(app.UIAxes2, 'MC')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Select "Load" to load a volume...'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_BDS3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end